classdef gameUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GAMEOVERLabel        matlab.ui.control.Label
        Rock_Obstacle        matlab.ui.control.Image
        StartGameButton      matlab.ui.control.StateButton
        Player               matlab.ui.control.Image
        ScoreEditField       matlab.ui.control.NumericEditField
        ScoreEditFieldLabel  matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Keeps track of the current game state 
        % 0 = Game over; 1 = Game running
        gameRunning; 
        gameTimer; %Timer for controlling game loop

        %Player jumping properties
        isJumping = false;
        jumpVelocity = 20;
        jumpHeight = 100;
        currentJumpY = 0;
    end
    
    methods (Access = public)
        
        function obstacleMovement(app)
            % disp(['Rock Position Before: ', num2str(app.Rock_Obstacle.Position(1))]);
            % if app.Rock_Obstacle.Position(1) > -app.Rock_Obstacle.Position(3)  % Check if it's completely off the screen
            if app.Rock_Obstacle.Position(1) > 0
                app.Rock_Obstacle.Position(1) = app.Rock_Obstacle.Position(1) - 8;  % Move left
            else
                app.Rock_Obstacle.Position(1) = app.UIFigure.Position(3);  % Reset to the right side of the figure
            end
            % disp(['Rock Position After: ', num2str(app.Rock_Obstacle.Position(1))]);
        end

    end
    
    methods (Access = private)
        
        function setUpGameTimer(app)
            app.gameTimer = timer('ExecutionMode','fixedRate', ...
                'Period', 0.05,  ... %Speed of game loop
                'TimerFcn', @(src, event)gameLoop(app));
            
        end
        
        function gameLoop(app)
            % disp('Game loop running');
            obstacleMovement(app); %Move obstacle

            if app.isJumping % If player is jumping
                updateJump(app);
            end

            if isGameOver(app) % If the game is over
                disp('collided');
                app.GAMEOVERLabel.Visible = "on";
                stopGame(app);
            end

            if isSuccessfulJump(app)
                app.ScoreEditField.Value = app.ScoreEditField.Value + 1;
            end
        end
        
        function resetGame(app)
            % Reset game elements to initial state
            % Position: [left bottom width height]
            app.Rock_Obstacle.Position = [958 100 65 41]; % Reset obstacle position
            app.isJumping = false;
            app.Player.Position = [100 100 75 75]; % Reset player position
            app.ScoreEditField.Value = 0;  % Reset score
        end

        function success = isSuccessfulJump(app)
            rockRight = app.Rock_Obstacle.Position(1) + app.Rock_Obstacle.Position(3);
            playerLeft = app.Player.Position(1);

            success = playerLeft > rockRight;
        end

        function gameOver = isGameOver(app) % Check if player collided with obstacle
            % Simple collision detection
            % gameOver = app.Player.Position(1) == app.Rock_Obstacle.Position(1);
            
            % Collision Detection
            % Vertical: Player.bottom <= Rock.top
            % Horizontal: 
            % Player.left <= Rock.left && Player.right >= Rock.left ||
            % Player.left <= Rock.right && Player.right >= Rock.right

            rockTop = app.Rock_Obstacle.Position(2) + app.Rock_Obstacle.Position(4);
            rockLeft = app.Rock_Obstacle.Position(1);
            rockRight = app.Rock_Obstacle.Position(1) + app.Rock_Obstacle.Position(3);
            playerBottom = app.Player.Position(2);
            playerLeft = app.Player.Position(1);
            playerRight = app.Player.Position(1) + app.Player.Position(3);

            xMatch = (playerLeft <= rockLeft & playerRight >= rockLeft) || ...
                (playerLeft <= rockRight & playerRight >= rockRight);
            yMatch = playerBottom <= rockTop;
            gameOver = xMatch & yMatch;
            
        end

        
        function updateJump(app)
            if app.isJumping 
                newY = app.Player.Position(2) + app.jumpVelocity;
                maxJumpReach = app.currentJumpY + app.jumpHeight;
                if newY > maxJumpReach
                    newY = maxJumpReach;
                    app.jumpVelocity = -app.jumpVelocity; 
                elseif newY < app.currentJumpY
                        newY = app.currentJumpY;
                        app.isJumping = false;
                        app.jumpVelocity = abs(app.jumpVelocity);
                end
                app.Player.Position(2) = newY;
            
            end
        end
        
        function stopGame(app)
            stop(app.gameTimer);  % Stop game timer
            app.gameRunning = 0;  % Update game state
            app.StartGameButton.Text = "Start Game";  % Update button text
            app.Player.Visible = "off";  % Hide player
            app.Rock_Obstacle.Visible = "off";  % Hide obstacle
            % app.StartGameButton.Value = 0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function UIFiguresStartupFcn(app)
            setUpGameTimer(app); %Initialized game timer on component creation "game start"
        end

        % Value changed function: StartGameButton
        function StartButtonPressed(app, event)
            % value is 0 on default/unpressed, 1 when pressed
            app.gameRunning = app.StartGameButton.Value; 

            if ~app.gameRunning  
                resetGame(app); %Resets the game 
                stopGame(app)
            else                   
                % If the game is running
                % Display "End Game" button
                app.StartGameButton.Text = "End Game";
                app.GAMEOVERLabel.Visible = "off";
                % Show Images
                app.Player.Visible = "on";
                app.Rock_Obstacle.Visible = "on";
                start(app.gameTimer); %Start gameTimer

                % Make the obstacle move
                obstacleMovement(app);
            end   
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            if(strcmp(event.Key, 'space') && ~app.isJumping)
                app.isJumping = true;
                app.currentJumpY = app.Player.Position(2);
            end       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ScoreEditFieldLabel = uilabel(app.UIFigure);
            app.ScoreEditFieldLabel.Text = 'Score';
            app.ScoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.Player = uiimage(app.UIFigure);
            app.Player.Visible = 'off';
            app.Player.ImageSource = fullfile(pathToMLAPP, 'mickey_mouse.png');
            app.StartGameButton = uibutton(app.UIFigure, 'state');
            app.StartGameButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonPressed, true);
            app.StartGameButton.Text = 'Start Game';
            app.Rock_Obstacle = uiimage(app.UIFigure);
            app.Rock_Obstacle.Visible = 'off';
            app.Rock_Obstacle.ImageSource = fullfile(pathToMLAPP, 'rock_obstacle_transparent.png');
            app.GAMEOVERLabel = uilabel(app.UIFigure);
            app.GAMEOVERLabel.Visible = 'off';
            app.GAMEOVERLabel.Text = 'GAME OVER';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gameUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @UIFiguresStartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end